classdef MonteHallScheduler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        begin                matlab.ui.control.Button
        Door3Contents        matlab.ui.control.Label
        Door2Contents        matlab.ui.control.Label
        Door1Contents        matlab.ui.control.Label
        Input                matlab.ui.control.TextArea
        InputsTextAreaLabel  matlab.ui.control.Label
        ResetButton          matlab.ui.control.Button
        Image_3              matlab.ui.control.Image
        Image_2              matlab.ui.control.Image
        Image                matlab.ui.control.Image
        Door1Label           matlab.ui.control.Label
        Door2Label           matlab.ui.control.Label
        Door3Label           matlab.ui.control.Label
        Door1                matlab.ui.control.Image
        Door2                matlab.ui.control.Image
        Door3                matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Door1
        function Door1Clicked(app, event)
            app.Door1Contents.Visible="on"
            app.Door1.Visible="off"
            if contains(app.Door1Contents.Text,"Chunky")
                app.Image.Visible="on"
            end
        end

        % Button pushed function: begin
        function beginButtonPushed(app, event)
            %Grab Starting values
            opts = app.Input.Value;
            opts = split(opts,",");
            %Construct random options
            if length(opts)<3
                sz=1;
            else
                sz = floor(length(opts)/2);
            end
            C = cell(sz,1);
            for k=1:sz
                C{k} = "Chunky";
            end
            C = string(C);
            S = cell(sz,1)
            for k=1:sz
                S{k} = "Steal";
            end
            S = string(S);

            opts = [opts;C;S];
            %Randomize options
            randind = randperm(length(opts))
            opts = opts(randind)
            %fill doors
            app.Door1Contents.Text = opts(1)
            app.Door2Contents.Text = opts(2)
            app.Door3Contents.Text = opts(3)
        end

        % Image clicked function: Door2
        function Door2ImageClicked(app, event)
            app.Door2Contents.Visible="on"
            app.Door2.Visible="off"
            if contains(app.Door2Contents.Text,"Chunky")
                app.Image_2.Visible="on"
            end
        end

        % Image clicked function: Door3
        function Door3ImageClicked(app, event)
            app.Door3Contents.Visible="on"
            app.Door3.Visible="off"
            if contains(app.Door3Contents.Text,"Chunky")
                app.Image_3.Visible="on"
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Door1Contents.Visible="off";
            app.Door2Contents.Visible="off";
            app.Door3Contents.Visible="off";
            app.Door1.Visible="on";
            app.Door2.Visible="on";
            app.Door3.Visible="on";
            app.Image.Visible="off";
            app.Image_2.Visible="off";
            app.Image_3.Visible="off";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Door3 = uiimage(app.UIFigure);
            app.Door3.ImageClickedFcn = createCallbackFcn(app, @Door3ImageClicked, true);
            app.Door3.ImageSource = fullfile(pathToMLAPP, 'door.jpg');
            app.Door2 = uiimage(app.UIFigure);
            app.Door2.ImageClickedFcn = createCallbackFcn(app, @Door2ImageClicked, true);
            app.Door2.ImageSource = fullfile(pathToMLAPP, 'door.jpg');
            app.Door1 = uiimage(app.UIFigure);
            app.Door1.ImageClickedFcn = createCallbackFcn(app, @Door1Clicked, true);
            app.Door1.ImageSource = fullfile(pathToMLAPP, 'door.jpg');
            app.Door3Label = uilabel(app.UIFigure);
            app.Door3Label.Text = 'Door 3';
            app.Door2Label = uilabel(app.UIFigure);
            app.Door2Label.Text = 'Door 2';
            app.Door1Label = uilabel(app.UIFigure);
            app.Door1Label.Text = 'Door 1';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'itsachunky.jpg');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.Visible = 'off';
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'itsachunky.jpg');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.Visible = 'off';
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'itsachunky.jpg');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InputsTextAreaLabel = uilabel(app.UIFigure);
            app.InputsTextAreaLabel.Text = 'Inputs';
            app.Input = uitextarea(app.UIFigure);
            app.Door1Contents = uilabel(app.UIFigure);
            app.Door1Contents.Visible = 'off';
            app.Door1Contents.Text = '';
            app.Door2Contents = uilabel(app.UIFigure);
            app.Door2Contents.Visible = 'off';
            app.Door2Contents.Text = '';
            app.Door3Contents = uilabel(app.UIFigure);
            app.Door3Contents.Visible = 'off';
            app.Door3Contents.Text = '';
            app.begin = uibutton(app.UIFigure, 'push');
            app.begin.ButtonPushedFcn = createCallbackFcn(app, @beginButtonPushed, true);
            app.begin.Text = 'Begin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MonteHallScheduler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end